classdef app6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        VerificationOfTheveninsTheoremLabel  matlab.ui.control.Label
        TextArea            matlab.ui.control.TextArea
        TheoryButton        matlab.ui.control.Button
        ProcedureButton     matlab.ui.control.Button
        SimulatorButton     matlab.ui.control.Button
        QuestionsButton     matlab.ui.control.Button
        VideoLectureButton  matlab.ui.control.Button
        ReferencesButton    matlab.ui.control.Button
        OBJECTIVELabel      matlab.ui.control.Label
        TextArea_2          matlab.ui.control.TextArea
        ObjectiveButton     matlab.ui.control.Button
        ApparatusButton     matlab.ui.control.Button
        TextArea_3          matlab.ui.control.TextArea
        APPARATUSLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ObjectiveButton
        function ObjectiveButtonPushed(app, event)
            app.TextArea_2.Visible='on';
            app.OBJECTIVELabel.Visible='on';
            app.TextArea_3.Visible='off';
            app.APPARATUSLabel.Visible='off';
        end

        % Button pushed function: ApparatusButton
        function ApparatusButtonPushed(app, event)
            app.TextArea_2.Visible='off';
            app.OBJECTIVELabel.Visible='off';
            app.TextArea_3.Visible='on';
            app.APPARATUSLabel.Visible='on';
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Callback function
        function RESETButtonPushed(app, event)
            app.TextArea_2.Visible='off';
            app.OBJECTIVELabel.Visible='off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VerificationOfTheveninsTheoremLabel = uilabel(app.UIFigure);
            app.VerificationOfTheveninsTheoremLabel.Text = 'Verification Of Thevenin''s Theorem';
            app.TextArea = uitextarea(app.UIFigure);
            app.TheoryButton = uibutton(app.UIFigure, 'push');
            app.TheoryButton.Icon = 'Theoryicon.png';
            app.TheoryButton.Text = '      Theory     ';
            app.ProcedureButton = uibutton(app.UIFigure, 'push');
            app.ProcedureButton.Icon = 'procedureicon.png';
            app.ProcedureButton.Text = '    Procedure   ';
            app.SimulatorButton = uibutton(app.UIFigure, 'push');
            app.SimulatorButton.Icon = 'simulatoricon.png';
            app.SimulatorButton.Text = '      Simulator    ';
            app.QuestionsButton = uibutton(app.UIFigure, 'push');
            app.QuestionsButton.Icon = 'quizicon.png';
            app.QuestionsButton.Text = '     Questions    ';
            app.VideoLectureButton = uibutton(app.UIFigure, 'push');
            app.VideoLectureButton.Icon = 'Videolecturesicon.png';
            app.VideoLectureButton.Text = '   Video Lecture';
            app.ReferencesButton = uibutton(app.UIFigure, 'push');
            app.ReferencesButton.Icon = 'referencesicon.png';
            app.ReferencesButton.Text = '    References';
            app.OBJECTIVELabel = uilabel(app.UIFigure);
            app.OBJECTIVELabel.Text = 'OBJECTIVE';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'   To study and verify Thevenin’s theorem.'};
            app.ObjectiveButton = uibutton(app.UIFigure, 'push');
            app.ObjectiveButton.ButtonPushedFcn = createCallbackFcn(app, @ObjectiveButtonPushed, true);
            app.ObjectiveButton.Icon = 'Objective.png';
            app.ObjectiveButton.Text = '      Objective     ';
            app.ApparatusButton = uibutton(app.UIFigure, 'push');
            app.ApparatusButton.ButtonPushedFcn = createCallbackFcn(app, @ApparatusButtonPushed, true);
            app.ApparatusButton.Icon = 'Apparatus.png';
            app.ApparatusButton.Text = '    Apparatus   ';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Visible = 'off';
            app.TextArea_3.Value = {'Apparatus Required'};
            app.APPARATUSLabel = uilabel(app.UIFigure);
            app.APPARATUSLabel.Visible = 'off';
            app.APPARATUSLabel.Text = 'APPARATUS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end